classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BTRead                     matlab.ui.control.Label
        BluetoothConnectionSuccessfulLabel  matlab.ui.control.Label
        ConnectBluetoothButton     matlab.ui.control.Button
        MapOutEnviornmentButton    matlab.ui.control.Button
        LocalizeinMapButton        matlab.ui.control.Button
        SendPathButton             matlab.ui.control.Button
        GeneratePathButton         matlab.ui.control.Button
        MapNameEditField           matlab.ui.control.EditField
        SaveMapButton              matlab.ui.control.Button
        LoadMapButton              matlab.ui.control.Button
        SendTopologicalPathButton  matlab.ui.control.Button
        TopologicalPathEditField   matlab.ui.control.EditField
        TopologicalPathLabel       matlab.ui.control.Label
        SelectModeDropDown         matlab.ui.control.DropDown
        SelectModeLabel            matlab.ui.control.Label
        EndLocationLabel           matlab.ui.control.Label
        EndLocationX_TextBox       matlab.ui.control.TextArea
        Label_4                    matlab.ui.control.Label
        EndLocationY_TextBox       matlab.ui.control.TextArea
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        StartLocationY_TextBox     matlab.ui.control.TextArea
        Label                      matlab.ui.control.Label
        StartLocationX_TextBox     matlab.ui.control.TextArea
        StartLocationLabel         matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        topPath % Stores the topological path
        mapTextFileName % Gives name of text file to load for map
        r1
        l1
        l2
        delete_complete
        bt
        btConnected
    end
    
    methods (Access = private)
        
        function deleteLines(app)
            delete(app.l1);
            delete(app.r1);
            delete(app.l2);
            app. delete_complete = true; 
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.btConnected = false;
            app.delete_complete = false;
        end

        % Value changed function: SelectModeDropDown
        function SelectModeDropDownValueChanged(app, event)
            value = app.SelectModeDropDown.Value;
            if (value == "Metric Path") 
                if (~app.delete_complete)
                    deleteLines(app);
                    app.mapTextFileName = '';
                    app.MapNameEditField.Value = '';
                end
                % Make bluetooth connection button visible
                if (~app.btConnected)
                    app.ConnectBluetoothButton.Visible = 'on';
                end
                % Make start location visible
                app.StartLocationLabel.Visible = 'on';
                app.StartLocationX_TextBox.Visible = 'on';
                app.Label.Visible = 'on';
                app.StartLocationY_TextBox.Visible = 'on';
                app.Label_2.Visible = 'on';
                % Make end location visible
                app.EndLocationLabel.Visible = 'on';
                app.EndLocationX_TextBox.Visible = 'on';
                app.Label_4.Visible = 'on';
                app.EndLocationY_TextBox.Visible = 'on';
                app.Label_3.Visible = 'on';
                % Make Topological Path boxes not visible
                app.topPath = '';
                app.TopologicalPathEditField.Value = '';
                app.TopologicalPathLabel.Visible = 'off';
                app.TopologicalPathEditField.Visible = 'off';
                app.SendTopologicalPathButton.Visible = 'off';
                % Make Map view and load visible
                app.LoadMapButton.Visible = 'on';
                app.SaveMapButton.Visible = 'on';
                app.MapNameEditField.Visible = 'on';
                app.UIAxes.Visible = 'on';
                % Make Path generation buttons visible
                app.GeneratePathButton.Visible = 'on';
                app.SendPathButton.Visible = 'on';
                % Make localization button not visible
                app.LocalizeinMapButton.Visible = 'off';
                % Make mapping button not visible
                app.MapOutEnviornmentButton.Visible = 'off';
                
                app.BTRead.Visible = 'off';
                
            elseif (value == "Localization")
                if (~app.delete_complete)
                    deleteLines(app);
                    app.mapTextFileName = '';
                    app.MapNameEditField.Value = '';
                end
                % Make bluetooth connection button visible
                if (~app.btConnected)
                    app.ConnectBluetoothButton.Visible = 'on';
                end
                 % Make start location visible
                app.StartLocationLabel.Visible = 'on';
                app.StartLocationX_TextBox.Visible = 'on';
                app.Label.Visible = 'on';
                app.StartLocationY_TextBox.Visible = 'on';
                app.Label_2.Visible = 'on';
                % Make end location visible
                app.EndLocationLabel.Visible = 'on';
                app.EndLocationX_TextBox.Visible = 'on';
                app.Label_4.Visible = 'on';
                app.EndLocationY_TextBox.Visible = 'on';
                app.Label_3.Visible = 'on';
                % Make Topological Path boxes not visible
                app.topPath = '';
                app.TopologicalPathEditField.Value = '';
                app.TopologicalPathLabel.Visible = 'off';
                app.TopologicalPathEditField.Visible = 'off';
                app.SendTopologicalPathButton.Visible = 'off';
                % Make Map view and load visible
                app.LoadMapButton.Visible = 'on';
                app.SaveMapButton.Visible = 'on';
                app.MapNameEditField.Visible = 'on';
                app.UIAxes.Visible = 'on';
                % Make Path generation buttons not visible
                app.GeneratePathButton.Visible = 'off';
                app.SendPathButton.Visible = 'off';
                % Make localization button visible
                app.LocalizeinMapButton.Visible = 'on';
                % Make mapping button not visible
                app.MapOutEnviornmentButton.Visible = 'off';
                
                app.BTRead.Visible = 'off';
                
            elseif (value == "Topological Path")
                if (~app.delete_complete)
                    deleteLines(app);
                    app.mapTextFileName = '';
                    app.MapNameEditField.Value = '';
                end
                % Make bluetooth connection button visible
                if (~app.btConnected)
                    app.ConnectBluetoothButton.Visible = 'on';
                end
                % Make start location not visible
                app.StartLocationLabel.Visible = 'off';
                app.StartLocationX_TextBox.Visible = 'off';
                app.Label.Visible = 'off';
                app.StartLocationY_TextBox.Visible = 'off';
                app.Label_2.Visible = 'off';
                % Make end location not visible
                app.EndLocationLabel.Visible = 'off';
                app.EndLocationX_TextBox.Visible = 'off';
                app.Label_4.Visible = 'off';
                app.EndLocationY_TextBox.Visible = 'off';
                app.Label_3.Visible = 'off';
                % Make Topological Path boxes visible
                app.TopologicalPathLabel.Visible = 'on';
                app.TopologicalPathEditField.Visible = 'on';
                app.SendTopologicalPathButton.Visible = 'off';
                % Make Map view and load not visible
                app.LoadMapButton.Visible = 'off';
                app.SaveMapButton.Visible = 'off';
                app.MapNameEditField.Visible = 'off';
                app.UIAxes.Visible = 'off';
                % Make Path generation buttons not visible
                app.GeneratePathButton.Visible = 'off';
                app.SendPathButton.Visible = 'off';
                % Make localization button not visible
                app.LocalizeinMapButton.Visible = 'off';
                % Make mapping button not visible
                app.MapOutEnviornmentButton.Visible = 'off';
                
                app.BTRead.Visible = 'off';
                
            elseif (value == "Mapping")
                if (~app.delete_complete)
                    deleteLines(app);
                    app.mapTextFileName = '';
                    app.MapNameEditField.Value = '';
                end
                % Make bluetooth connection button visible
                if (~app.btConnected)
                    app.ConnectBluetoothButton.Visible = 'on';
                end
                % Make start location not visible
                app.StartLocationLabel.Visible = 'off';
                app.StartLocationX_TextBox.Visible = 'off';
                app.Label.Visible = 'off';
                app.StartLocationY_TextBox.Visible = 'off';
                app.Label_2.Visible = 'off';
                % Make end location not visible
                app.EndLocationLabel.Visible = 'off';
                app.EndLocationX_TextBox.Visible = 'off';
                app.Label_4.Visible = 'off';
                app.EndLocationY_TextBox.Visible = 'off';
                app.Label_3.Visible = 'off';
                % Make Topological Path boxes not visible
                app.topPath = '';
                app.TopologicalPathEditField.Value = '';
                app.TopologicalPathLabel.Visible = 'off';
                app.TopologicalPathEditField.Visible = 'off';
                app.SendTopologicalPathButton.Visible = 'off';
                % Make Map view and load visible
                app.LoadMapButton.Visible = 'on';
                app.SaveMapButton.Visible = 'on';
                app.MapNameEditField.Visible = 'on';
                app.UIAxes.Visible = 'on';
                % Make Path generation buttons not visible
                app.GeneratePathButton.Visible = 'off';
                app.SendPathButton.Visible = 'off';
                % Make localization button not visible
                app.LocalizeinMapButton.Visible = 'off';
                % Make mapping button visible
                app.MapOutEnviornmentButton.Visible = 'on';
                
                app.BTRead.Visible = 'off';
                
            else
                if (~app.delete_complete)
                    deleteLines(app);
                    app.mapTextFileName = '';
                    app.MapNameEditField.Value = '';
                end
                % Make bluetooth connection button not visible
                app.ConnectBluetoothButton.Visible = 'off';
                % Make start location not visible
                app.StartLocationLabel.Visible = 'off';
                app.StartLocationX_TextBox.Visible = 'off';
                app.Label.Visible = 'off';
                app.StartLocationY_TextBox.Visible = 'off';
                app.Label_2.Visible = 'off';
                % Make end location not visible
                app.EndLocationLabel.Visible = 'off';
                app.EndLocationX_TextBox.Visible = 'off';
                app.Label_4.Visible = 'off';
                app.EndLocationY_TextBox.Visible = 'off';
                app.Label_3.Visible = 'off';
                % Make Topological Path boxes not visible
                app.topPath = '';
                app.TopologicalPathEditField.Value = '';
                app.TopologicalPathLabel.Visible = 'off';
                app.TopologicalPathEditField.Visible = 'off';
                app.SendTopologicalPathButton.Visible = 'off';
                % Make Map view and load not visible
                app.LoadMapButton.Visible = 'off';
                app.SaveMapButton.Visible = 'off';
                app.MapNameEditField.Visible = 'off';
                app.UIAxes.Visible = 'off';
                % Make Path generation buttons not visible
                app.GeneratePathButton.Visible = 'off';
                app.SendPathButton.Visible = 'off';
                % Make localization button not visible
                app.LocalizeinMapButton.Visible = 'off';
                % Make mapping button not visible
                app.MapOutEnviornmentButton.Visible = 'off';
                
                app.BTRead.Visible = 'off';
            end
        end

        % Button pushed function: SendTopologicalPathButton
        function SendTopologicalPathButtonPushed(app, event)
            fwrite(app.bt,'~T');
            pause(0.1);
            fwrite(app.bt,append(app.topPath,'-'));
            
            app.BTRead.Visible = 'on';
            app.BTRead.Text = fscanf(app.bt);
        end

        % Value changing function: TopologicalPathEditField
        function TopologicalPathEditFieldValueChanging(app, event)
            app.SendTopologicalPathButton.Visible = 'on';
            app.BTRead.Text = '';
        end

        % Button pushed function: LoadMapButton
        function LoadMapButtonPushed(app, event)
            map = load(app.mapTextFileName);
            [numRows,numCols] = size(map);
            axis(app.UIAxes,[-1 numCols+1 -1 numRows+1])
            app.r1 = rectangle(app.UIAxes,'Position',[0 0 numCols numRows],'LineWidth',2);
            linecount = 1;
            app.delete_complete = false;
            for r = 1:numRows
                for c = 1:numCols
                    if(bitget(map(r,c),1)==1)
                        x1 = c-1; x2 = c;
                        y1 = numRows-(r-1); y2 = numRows-(r-1);
                        app.l1(linecount) = line(app.UIAxes,[x1 x2],[y1 y2],'color','k','LineWidth',2);
                        linecount = linecount + 1;
                    end
                    if(bitget(map(r,c),2)==1)
                        x1 = c; x2 = c;
                        y1 = numRows-(r-1); y2 = numRows-r;
                        app.l2(linecount) = line(app.UIAxes,[x1 x2],[y1 y2],'color','k','LineWidth',2);
                        linecount = linecount + 1;
                    end
                end
            end
        end

        % Value changed function: MapNameEditField
        function MapNameEditFieldValueChanged(app, event)
            app.mapTextFileName = app.MapNameEditField.Value; 
        end

        % Button pushed function: MapOutEnviornmentButton
        function MapOutEnviornmentButtonPushed(app, event)
            % TODO: Call function
        end

        % Button pushed function: SendPathButton
        function SendPathButtonPushed(app, event)
            % TODO: Call Function
        end

        % Button pushed function: GeneratePathButton
        function GeneratePathButtonPushed(app, event)
            % TODO: Call Function
        end

        % Button pushed function: LocalizeinMapButton
        function LocalizeinMapButtonPushed(app, event)
            % TODO: Call Function
        end

        % Value changed function: TopologicalPathEditField
        function TopologicalPathEditFieldValueChanged(app, event)
            app.topPath = app.TopologicalPathEditField.Value;          
        end

        % Button pushed function: ConnectBluetoothButton
        function ConnectBluetoothButtonPushed(app, event)
            app.bt = Bluetooth('BRAITENBERG_BT',1);
            fopen(app.bt);
            if(app.bt.status == 'open')
                app.ConnectBluetoothButton.Visible = 'off';
                app.BluetoothConnectionSuccessfulLabel.Visible = 'on';
                app.btConnected = true;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.btConnected)
                fclose(app.bt);
            end
            delete(app)   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map')
            app.UIAxes.Visible = 'off';
            app.StartLocationLabel = uilabel(app.UIFigure);
            app.StartLocationLabel.Visible = 'off';
            app.StartLocationLabel.Text = 'Start Location: ( ';
            app.StartLocationX_TextBox = uitextarea(app.UIFigure);
            app.StartLocationX_TextBox.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = ', ';
            app.StartLocationY_TextBox = uitextarea(app.UIFigure);
            app.StartLocationY_TextBox.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = ')';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = ')';
            app.EndLocationY_TextBox = uitextarea(app.UIFigure);
            app.EndLocationY_TextBox.Visible = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = ', ';
            app.EndLocationX_TextBox = uitextarea(app.UIFigure);
            app.EndLocationX_TextBox.Visible = 'off';
            app.EndLocationLabel = uilabel(app.UIFigure);
            app.EndLocationLabel.Visible = 'off';
            app.EndLocationLabel.Text = 'End Location: ( ';
            app.SelectModeLabel = uilabel(app.UIFigure);
            app.SelectModeLabel.Text = 'Select Mode';
            app.SelectModeDropDown = uidropdown(app.UIFigure);
            app.SelectModeDropDown.Items = {'Main', 'Topological Path', 'Metric Path', 'Localization', 'Mapping'};
            app.SelectModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModeDropDownValueChanged, true);
            app.SelectModeDropDown.Value = 'Main';
            app.TopologicalPathLabel = uilabel(app.UIFigure);
            app.TopologicalPathLabel.Visible = 'off';
            app.TopologicalPathLabel.Text = 'Topological Path:';
            app.TopologicalPathEditField = uieditfield(app.UIFigure, 'text');
            app.TopologicalPathEditField.ValueChangedFcn = createCallbackFcn(app, @TopologicalPathEditFieldValueChanged, true);
            app.TopologicalPathEditField.ValueChangingFcn = createCallbackFcn(app, @TopologicalPathEditFieldValueChanging, true);
            app.TopologicalPathEditField.Visible = 'off';
            app.SendTopologicalPathButton = uibutton(app.UIFigure, 'push');
            app.SendTopologicalPathButton.ButtonPushedFcn = createCallbackFcn(app, @SendTopologicalPathButtonPushed, true);
            app.SendTopologicalPathButton.Visible = 'off';
            app.SendTopologicalPathButton.Text = 'Send Path';
            app.LoadMapButton = uibutton(app.UIFigure, 'push');
            app.LoadMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMapButton.Visible = 'off';
            app.LoadMapButton.Text = 'Load Map';
            app.SaveMapButton = uibutton(app.UIFigure, 'push');
            app.SaveMapButton.Visible = 'off';
            app.SaveMapButton.Text = 'Save Map';
            app.MapNameEditField = uieditfield(app.UIFigure, 'text');
            app.MapNameEditField.ValueChangedFcn = createCallbackFcn(app, @MapNameEditFieldValueChanged, true);
            app.MapNameEditField.Visible = 'off';
            app.GeneratePathButton = uibutton(app.UIFigure, 'push');
            app.GeneratePathButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePathButtonPushed, true);
            app.GeneratePathButton.Visible = 'off';
            app.GeneratePathButton.Text = 'Generate Path';
            app.SendPathButton = uibutton(app.UIFigure, 'push');
            app.SendPathButton.ButtonPushedFcn = createCallbackFcn(app, @SendPathButtonPushed, true);
            app.SendPathButton.Visible = 'off';
            app.SendPathButton.Text = 'Send Path';
            app.LocalizeinMapButton = uibutton(app.UIFigure, 'push');
            app.LocalizeinMapButton.ButtonPushedFcn = createCallbackFcn(app, @LocalizeinMapButtonPushed, true);
            app.LocalizeinMapButton.Visible = 'off';
            app.LocalizeinMapButton.Text = 'Localize in Map';
            app.MapOutEnviornmentButton = uibutton(app.UIFigure, 'push');
            app.MapOutEnviornmentButton.ButtonPushedFcn = createCallbackFcn(app, @MapOutEnviornmentButtonPushed, true);
            app.MapOutEnviornmentButton.Visible = 'off';
            app.MapOutEnviornmentButton.Text = 'Map Out Enviornment';
            app.ConnectBluetoothButton = uibutton(app.UIFigure, 'push');
            app.ConnectBluetoothButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectBluetoothButtonPushed, true);
            app.ConnectBluetoothButton.Visible = 'off';
            app.ConnectBluetoothButton.Text = 'Connect Bluetooth';
            app.BluetoothConnectionSuccessfulLabel = uilabel(app.UIFigure);
            app.BluetoothConnectionSuccessfulLabel.Visible = 'off';
            app.BluetoothConnectionSuccessfulLabel.Text = 'Bluetooth Connection Successful';
            app.BTRead = uilabel(app.UIFigure);
            app.BTRead.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end